classdef tugas1gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        OriginalTab                 matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        TabGroup2                   matlab.ui.container.TabGroup
        GrayTab                     matlab.ui.container.Tab
        GridLayout7                 matlab.ui.container.GridLayout
        UIAxes                      matlab.ui.control.UIAxes
        RedTab                      matlab.ui.container.Tab
        GridLayout7_2               matlab.ui.container.GridLayout
        UIAxesR                     matlab.ui.control.UIAxes
        GreenTab                    matlab.ui.container.Tab
        GridLayout7_3               matlab.ui.container.GridLayout
        UIAxesG                     matlab.ui.control.UIAxes
        BlueTab                     matlab.ui.container.Tab
        GridLayout7_4               matlab.ui.container.GridLayout
        UIAxesB                     matlab.ui.control.UIAxes
        Image                       matlab.ui.control.Image
        ProcessedTab                matlab.ui.container.Tab
        GridLayout10                matlab.ui.container.GridLayout
        Image2                      matlab.ui.control.Image
        TabGroup3                   matlab.ui.container.TabGroup
        GrayTab_2                   matlab.ui.container.Tab
        GridLayout11                matlab.ui.container.GridLayout
        UIAxes3                     matlab.ui.control.UIAxes
        RedTab_2                    matlab.ui.container.Tab
        GridLayout11_2              matlab.ui.container.GridLayout
        UIAxes3_2                   matlab.ui.control.UIAxes
        GreenTab_2                  matlab.ui.container.Tab
        GridLayout11_3              matlab.ui.container.GridLayout
        UIAxes3_3                   matlab.ui.control.UIAxes
        BlueTab_2                   matlab.ui.container.Tab
        GridLayout11_4              matlab.ui.container.GridLayout
        UIAxes3_4                   matlab.ui.control.UIAxes
        RefrensiTab                 matlab.ui.container.Tab
        GridLayout10_2              matlab.ui.container.GridLayout
        Image2_2                    matlab.ui.control.Image
        TabGroup3_2                 matlab.ui.container.TabGroup
        GrayTab_3                   matlab.ui.container.Tab
        GridLayout11_5              matlab.ui.container.GridLayout
        UIAxes3_5                   matlab.ui.control.UIAxes
        RedTab_3                    matlab.ui.container.Tab
        GridLayout11_6              matlab.ui.container.GridLayout
        UIAxes3_6                   matlab.ui.control.UIAxes
        GreenTab_3                  matlab.ui.container.Tab
        GridLayout11_7              matlab.ui.container.GridLayout
        UIAxes3_7                   matlab.ui.control.UIAxes
        BlueTab_3                   matlab.ui.container.Tab
        GridLayout11_8              matlab.ui.container.GridLayout
        UIAxes3_8                   matlab.ui.control.UIAxes
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout13                matlab.ui.container.GridLayout
        StretchingCheckBox          matlab.ui.control.CheckBox
        NegativeCheckBox            matlab.ui.control.CheckBox
        InputGambarReferensiButton  matlab.ui.control.Button
        TransformasiLogPanel        matlab.ui.container.Panel
        GridLayout8_3               matlab.ui.container.GridLayout
        CheckBoxLog                 matlab.ui.control.CheckBox
        cEditField_2                matlab.ui.control.NumericEditField
        cEditField_2Label           matlab.ui.control.Label
        TransformasiPangkatPanel    matlab.ui.container.Panel
        GridLayout8_2               matlab.ui.container.GridLayout
        CheckBoxPangkat             matlab.ui.control.CheckBox
        gEditField                  matlab.ui.control.NumericEditField
        gEditFieldLabel             matlab.ui.control.Label
        cEditField                  matlab.ui.control.NumericEditField
        cEditFieldLabel             matlab.ui.control.Label
        ImageBrighteningPanel       matlab.ui.container.Panel
        GridLayout8                 matlab.ui.container.GridLayout
        CheckBoxBrightening         matlab.ui.control.CheckBox
        bEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        GridLayout12                matlab.ui.container.GridLayout
        ProcessButton               matlab.ui.control.Button
        InputGambarButton           matlab.ui.control.Button
        TypeDropDown                matlab.ui.control.DropDown
    end

    
     properties (Access = private)
        selectedFile
        selectedType = "Tipe 1"
        refImg
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            app.selectedType = value;
        end

        % Button pushed function: InputGambarButton
        function InputGambarButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                app.selectedFile = fullfile(path, file);
                
                app.Image.ImageSource = app.selectedFile;
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            img = imread(app.selectedFile);
            
            imgprcs = Tugas1Program;

            if size(img, 3) == 3
                rgbHistogram = imgprcs.calculateColoredHistogram(img);
                
                bar(app.UIAxesR, rgbHistogram(1, :));
                title(app.UIAxesR, 'Image Red Histogram');
                xlabel(app.UIAxesR, 'Pixel Intensity');
                ylabel(app.UIAxesR, 'Frequency');
                
                bar(app.UIAxesG, rgbHistogram(2, :));
                title(app.UIAxesG, 'Image Green Histogram');
                xlabel(app.UIAxesG, 'Pixel Intensity');
                ylabel(app.UIAxesG, 'Frequency');
                
                bar(app.UIAxesB, rgbHistogram(3, :));
                title(app.UIAxesB, 'Image Blue Histogram');
                xlabel(app.UIAxesB, 'Pixel Intensity');
                ylabel(app.UIAxesB, 'Frequency');
            else
                histogram = imgprcs.calculateGrayscaledHistogram(img);

                bar(app.UIAxes, histogram);
                title(app.UIAxes, 'Image Gray Histogram');
                xlabel(app.UIAxes, 'Pixel Intensity');
                ylabel(app.UIAxes, 'Frequency');
            end

            imgProcessed = img;

            if size(imgProcessed, 3) == 3
                rChannel = imgProcessed(:, :, 1);
                gChannel = imgProcessed(:, :, 2);
                bChannel = imgProcessed(:, :, 3);
                
                if app.selectedType == "Tipe 2"
                    if app.CheckBoxBrightening.Value
                        rChannel = imgprcs.imageBrightening(rChannel, app.aEditField.Value, app.bEditField.Value);
                        gChannel = imgprcs.imageBrightening(gChannel, app.aEditField.Value, app.bEditField.Value);
                        bChannel = imgprcs.imageBrightening(bChannel, app.aEditField.Value, app.bEditField.Value);
                    end
    
                    if app.CheckBoxPangkat.Value
                        rChannel = imgprcs.powerTransformation(rChannel, app.cEditField.Value, app.gEditField.Value);
                        gChannel = imgprcs.powerTransformation(gChannel, app.cEditField.Value, app.gEditField.Value);
                        bChannel = imgprcs.powerTransformation(bChannel, app.cEditField.Value, app.gEditField.Value);
                    end
    
                    if app.CheckBoxLog.Value
                        rChannel = imgprcs.logTransformation(rChannel, app.cEditField_2.Value);
                        gChannel = imgprcs.logTransformation(gChannel, app.cEditField_2.Value);
                        bChannel = imgprcs.logTransformation(bChannel, app.cEditField_2.Value);
                    end

                    if app.NegativeCheckBox.Value
                        rChannel = imgprcs.imageNegative(rChannel);
                        gChannel = imgprcs.imageNegative(gChannel);
                        bChannel = imgprcs.imageNegative(bChannel);
                    end

                    if app.StretchingCheckBox.Value
                        rChannel = imgprcs.contrastStretching(rChannel);
                        gChannel = imgprcs.contrastStretching(gChannel);
                        bChannel = imgprcs.contrastStretching(bChannel);
                    end
                elseif app.selectedType == "Tipe 3"
                    rChannel = imgprcs.histogramEqualization(rChannel);
                    gChannel = imgprcs.histogramEqualization(gChannel);
                    bChannel = imgprcs.histogramEqualization(bChannel);
                elseif app.selectedType == "Tipe 4"
                    rgbHistogram = imgprcs.calculateColoredHistogram(app.refImg);
                
                    bar(app.UIAxes3_6, rgbHistogram(1, :));
                    title(app.UIAxes3_6, 'Image Red Histogram');
                    xlabel(app.UIAxes3_6, 'Pixel Intensity');
                    ylabel(app.UIAxes3_6, 'Frequency');
                    
                    bar(app.UIAxes3_7, rgbHistogram(2, :));
                    title(app.UIAxes3_7, 'Image Green Histogram');
                    xlabel(app.UIAxes3_7, 'Pixel Intensity');
                    ylabel(app.UIAxes3_7, 'Frequency');

                    bar(app.UIAxes3_8, rgbHistogram(3, :));
                    title(app.UIAxes3_8, 'Image Blue Histogram');
                    xlabel(app.UIAxes3_8, 'Pixel Intensity');
                    ylabel(app.UIAxes3_8, 'Frequency');

                    rChannelRef = app.refImg(:, :, 1);
                    gChannelRef = app.refImg(:, :, 2);
                    bChannelRef = app.refImg(:, :, 3);

                    rChannel = imgprcs.histogramSpecification(rChannel, rChannelRef);
                    gChannel = imgprcs.histogramSpecification(gChannel, gChannelRef);
                    bChannel = imgprcs.histogramSpecification(bChannel, bChannelRef);
                end

                imgProcessed = cat(3, rChannel, gChannel, bChannel);

                rgbHistogram = imgprcs.calculateColoredHistogram(imgProcessed);

                bar(app.UIAxes3_2, rgbHistogram(1, :));
                title(app.UIAxes3_2, 'Image Red Histogram');
                xlabel(app.UIAxes3_2, 'Pixel Intensity');
                ylabel(app.UIAxes3_2, 'Frequency');

                bar(app.UIAxes3_3, rgbHistogram(2, :));
                title(app.UIAxes3_3, 'Image Green Histogram');
                xlabel(app.UIAxes3_3, 'Pixel Intensity');
                ylabel(app.UIAxes3_3, 'Frequency');
                
                bar(app.UIAxes3_4, rgbHistogram(3, :));
                title(app.UIAxes3_4, 'Image Blue Histogram');
                xlabel(app.UIAxes3_4, 'Pixel Intensity');
                ylabel(app.UIAxes3_4, 'Frequency');
            else
                if app.selectedType == "Tipe 2"
                    if app.CheckBoxBrightening.Value
                        imgProcessed = imgprcs.imageBrightening(imgProcessed, app.aEditField.Value, app.bEditField.Value);
                    end
    
                    if app.CheckBoxPangkat.Value
                        imgProcessed = imgprcs.powerTransformation(imgProcessed, app.cEditField.Value, app.gEditField.Value);
                    end
    
                    if app.CheckBoxLog.Value
                        imgProcessed = imgprcs.logTransformation(imgProcessed, app.cEditField_2.Value);
                    end

                    if app.NegativeCheckBox.Value
                        imgProcessed = imgprcs.imageNegative(imgProcessed);
                    end

                    if app.StretchingCheckBox.Value
                        imgProcessed = imgprcs.contrastStretching(imgProcessed);
                    end
                elseif app.selectedType == "Tipe 3"
                    imgProcessed = imgprcs.histogramEqualization(imgProcessed);
                elseif app.selectedType == "Tipe 4"
                    histogram = imgprcs.calculateGrayscaledHistogram(app.refImg);
                
                    bar(app.UIAxes3_5, histogram);
                    title(app.UIAxes3_5, 'Image Gray Histogram');
                    xlabel(app.UIAxes3_5, 'Pixel Intensity');
                    ylabel(app.UIAxes3_5, 'Frequency');

                    imgProcessed = imgprcs.histogramSpecification(imgProcessed, app.refImg);
                end

                histogram = imgprcs.calculateGrayscaledHistogram(imgProcessed);
            
                bar(app.UIAxes3, histogram);
                title(app.UIAxes3, 'Image Gray Histogram');
                xlabel(app.UIAxes3, 'Pixel Intensity');
                ylabel(app.UIAxes3, 'Frequency');

                imgProcessed = cat(3, imgProcessed, imgProcessed, imgProcessed);
            end

            app.Image2.ImageSource = imgProcessed; % show img processed
        end

        % Button pushed function: InputGambarReferensiButton
        function InputGambarReferensiButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                refFile = fullfile(path, file);
                
                imageRef = imread(refFile);
                img = imread(app.selectedFile);
                
                if all(size(imageRef) == size(img)) && all(size(imageRef,3) == size(img,3))
                    app.refImg = imageRef;
                    app.Image2_2.ImageSource = refFile;
                else
                    disp('Unmatched file size or dimension');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.GridLayout2);
            app.TypeDropDown = uidropdown(app.GridLayout12);
            app.TypeDropDown.Items = {'Tipe 1', 'Tipe 2', 'Tipe 3', 'Tipe 4'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Tipe 1';
            app.InputGambarButton = uibutton(app.GridLayout12, 'push');
            app.InputGambarButton.ButtonPushedFcn = createCallbackFcn(app, @InputGambarButtonPushed, true);
            app.InputGambarButton.Text = {'Input'; 'Gambar'};
            app.ProcessButton = uibutton(app.GridLayout12, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ImageBrighteningPanel = uipanel(app.GridLayout2);
            app.ImageBrighteningPanel.Title = 'Image Brightening';
            app.GridLayout8 = uigridlayout(app.ImageBrighteningPanel);
            app.aEditFieldLabel = uilabel(app.GridLayout8);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.GridLayout8, 'numeric');
            app.bEditFieldLabel = uilabel(app.GridLayout8);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.GridLayout8, 'numeric');
            app.CheckBoxBrightening = uicheckbox(app.GridLayout8);
            app.CheckBoxBrightening.Text = '';
            app.TransformasiPangkatPanel = uipanel(app.GridLayout2);
            app.TransformasiPangkatPanel.Title = 'Transformasi Pangkat';
            app.GridLayout8_2 = uigridlayout(app.TransformasiPangkatPanel);
            app.cEditFieldLabel = uilabel(app.GridLayout8_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.gEditFieldLabel = uilabel(app.GridLayout8_2);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.CheckBoxPangkat = uicheckbox(app.GridLayout8_2);
            app.CheckBoxPangkat.Text = '';
            app.TransformasiLogPanel = uipanel(app.GridLayout2);
            app.TransformasiLogPanel.Title = 'Transformasi Log';
            app.GridLayout8_3 = uigridlayout(app.TransformasiLogPanel);
            app.cEditField_2Label = uilabel(app.GridLayout8_3);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.GridLayout8_3, 'numeric');
            app.CheckBoxLog = uicheckbox(app.GridLayout8_3);
            app.CheckBoxLog.Text = '';
            app.GridLayout13 = uigridlayout(app.GridLayout2);
            app.InputGambarReferensiButton = uibutton(app.GridLayout13, 'push');
            app.InputGambarReferensiButton.ButtonPushedFcn = createCallbackFcn(app, @InputGambarReferensiButtonPushed, true);
            app.InputGambarReferensiButton.Text = {'Input Gambar'; 'Referensi'};
            app.NegativeCheckBox = uicheckbox(app.GridLayout13);
            app.NegativeCheckBox.Text = 'Negative';
            app.StretchingCheckBox = uicheckbox(app.GridLayout13);
            app.StretchingCheckBox.Text = 'Stretching';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.OriginalTab = uitab(app.TabGroup);
            app.OriginalTab.Title = 'Original';
            app.GridLayout6 = uigridlayout(app.OriginalTab);
            app.Image = uiimage(app.GridLayout6);
            app.TabGroup2 = uitabgroup(app.GridLayout6);
            app.GrayTab = uitab(app.TabGroup2);
            app.GrayTab.Title = 'Gray';
            app.GridLayout7 = uigridlayout(app.GrayTab);
            app.UIAxes = uiaxes(app.GridLayout7);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RedTab = uitab(app.TabGroup2);
            app.RedTab.Title = 'Red';
            app.GridLayout7_2 = uigridlayout(app.RedTab);
            app.UIAxesR = uiaxes(app.GridLayout7_2);
            xlabel(app.UIAxesR, 'X')
            ylabel(app.UIAxesR, 'Y')
            zlabel(app.UIAxesR, 'Z')
            app.GreenTab = uitab(app.TabGroup2);
            app.GreenTab.Title = 'Green';
            app.GridLayout7_3 = uigridlayout(app.GreenTab);
            app.UIAxesG = uiaxes(app.GridLayout7_3);
            xlabel(app.UIAxesG, 'X')
            ylabel(app.UIAxesG, 'Y')
            zlabel(app.UIAxesG, 'Z')
            app.BlueTab = uitab(app.TabGroup2);
            app.BlueTab.Title = 'Blue';
            app.GridLayout7_4 = uigridlayout(app.BlueTab);
            app.UIAxesB = uiaxes(app.GridLayout7_4);
            xlabel(app.UIAxesB, 'X')
            ylabel(app.UIAxesB, 'Y')
            zlabel(app.UIAxesB, 'Z')
            app.ProcessedTab = uitab(app.TabGroup);
            app.ProcessedTab.Title = 'Processed';
            app.GridLayout10 = uigridlayout(app.ProcessedTab);
            app.TabGroup3 = uitabgroup(app.GridLayout10);
            app.GrayTab_2 = uitab(app.TabGroup3);
            app.GrayTab_2.Title = 'Gray';
            app.GridLayout11 = uigridlayout(app.GrayTab_2);
            app.UIAxes3 = uiaxes(app.GridLayout11);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.RedTab_2 = uitab(app.TabGroup3);
            app.RedTab_2.Title = 'Red';
            app.GridLayout11_2 = uigridlayout(app.RedTab_2);
            app.UIAxes3_2 = uiaxes(app.GridLayout11_2);
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.GreenTab_2 = uitab(app.TabGroup3);
            app.GreenTab_2.Title = 'Green';
            app.GridLayout11_3 = uigridlayout(app.GreenTab_2);
            app.UIAxes3_3 = uiaxes(app.GridLayout11_3);
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.BlueTab_2 = uitab(app.TabGroup3);
            app.BlueTab_2.Title = 'Blue';
            app.GridLayout11_4 = uigridlayout(app.BlueTab_2);
            app.UIAxes3_4 = uiaxes(app.GridLayout11_4);
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            zlabel(app.UIAxes3_4, 'Z')
            app.Image2 = uiimage(app.GridLayout10);
            app.RefrensiTab = uitab(app.TabGroup);
            app.RefrensiTab.Title = 'Refrensi';
            app.GridLayout10_2 = uigridlayout(app.RefrensiTab);
            app.TabGroup3_2 = uitabgroup(app.GridLayout10_2);
            app.GrayTab_3 = uitab(app.TabGroup3_2);
            app.GrayTab_3.Title = 'Gray';
            app.GridLayout11_5 = uigridlayout(app.GrayTab_3);
            app.UIAxes3_5 = uiaxes(app.GridLayout11_5);
            xlabel(app.UIAxes3_5, 'X')
            ylabel(app.UIAxes3_5, 'Y')
            zlabel(app.UIAxes3_5, 'Z')
            app.RedTab_3 = uitab(app.TabGroup3_2);
            app.RedTab_3.Title = 'Red';
            app.GridLayout11_6 = uigridlayout(app.RedTab_3);
            app.UIAxes3_6 = uiaxes(app.GridLayout11_6);
            xlabel(app.UIAxes3_6, 'X')
            ylabel(app.UIAxes3_6, 'Y')
            zlabel(app.UIAxes3_6, 'Z')
            app.GreenTab_3 = uitab(app.TabGroup3_2);
            app.GreenTab_3.Title = 'Green';
            app.GridLayout11_7 = uigridlayout(app.GreenTab_3);
            app.UIAxes3_7 = uiaxes(app.GridLayout11_7);
            xlabel(app.UIAxes3_7, 'X')
            ylabel(app.UIAxes3_7, 'Y')
            zlabel(app.UIAxes3_7, 'Z')
            app.BlueTab_3 = uitab(app.TabGroup3_2);
            app.BlueTab_3.Title = 'Blue';
            app.GridLayout11_8 = uigridlayout(app.BlueTab_3);
            app.UIAxes3_8 = uiaxes(app.GridLayout11_8);
            title(app.UIAxes3_8, 'Title')
            xlabel(app.UIAxes3_8, 'X')
            ylabel(app.UIAxes3_8, 'Y')
            zlabel(app.UIAxes3_8, 'Z')
            app.Image2_2 = uiimage(app.GridLayout10_2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tugas1gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end